classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ModulationButtonGroup       matlab.ui.container.ButtonGroup
        BPSK6MpbsButton             matlab.ui.control.RadioButton
        QAM36MbpsButton             matlab.ui.control.RadioButton
        NumofTransEditFieldLabel    matlab.ui.control.Label
        NumofTransEditField         matlab.ui.control.NumericEditField
        PowerofNoiseEditFieldLabel  matlab.ui.control.Label
        PowerofNoiseEditField       matlab.ui.control.NumericEditField
        IEEE80211aOFDMPHYLabel      matlab.ui.control.Label
        SwitchLabel                 matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
        PowerofTransEditFieldLabel  matlab.ui.control.Label
        PowerofTransEditField       matlab.ui.control.NumericEditField
        SimulationLabel             matlab.ui.control.Label
        Version10Label              matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        TextArea                    matlab.ui.control.TextArea
        Image                       matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        flag = 1
        Number
        Power = 0
        Noise = 0
        index = 1
        snr   = 0
    end
    
    methods (Access = public)
        function main(app)
            clc
            %app.TextArea.Value{app.index} = sprintf('=================');
            app.index = app.index + 1;
            %app.TextArea.Value = [app.TextArea.Value{1} ' '];
            disp('IEEE_802.11a_PHY_Simulation v1.0');
            app.TextArea.Value{app.index} = sprintf('IEEE_802.11a_PHY_Simulation v1.0');
            app.index = app.index+1;
            disp('============================');
            app.TextArea.Value{app.index} = sprintf('============================');
            app.index = app.index+1;
            %flag=input('1. BPSK(6Mbps)\n2. 16QAM(36Mbps)\n');
            %Number=input('Numbers of Transmission:');
            %Noise=input('Power of Noise:');
            app.snr=app.Power-app.Noise;
            %Preamble
            Preamble=app.preamble();
            %Signal
            if app.flag==1
                Singal=[1 1 0 1 0 0 0 1 0 0 1 1 0 0 0 0 0 0 0 0 0 0 0 0];
                N=24;
                R=1/2;
                Q=1;
            elseif app.flag==2
                Singal=[1 0 1 1 0 0 0 1 0 0 1 1 0 0 0 0 0 0 0 0 0 0 0 0];
                N=144;
                R=3/4;
                Q=4;
            else disp('Input error!');
                return;
            end
            %Modulation----------------------------------------------------------------
            %Signal
            sdeConv=app.coding_conv(Singal,1);
            sInter=app.interleaving(sdeConv,1);
            sModu=app.cons_map(sInter,1);
            sPilot=app.add_pilot(sModu);
            sOFDMModu=app.IFFT64(sPilot);
            sCP=app.add_cp(sOFDMModu);
            %Data
            tmp=app.Number+(N-mod(app.Number,N));
            dTrans=randi([0,1],1,tmp);
            disp('Data of TX');
            app.TextArea.Value{app.index} = sprintf('Data of TX');
            app.index = app.index+1;
            tx_string = dTrans(1:app.Number)
            app.TextArea.Value{app.index} = sprintf('%d',tx_string);
            app.index = app.index+1;
            dScr=app.scramble(dTrans);
            dConv=app.coding_conv(dScr,app.flag);
            for K=0:length(dConv)/(N/R)-1
                dT=dConv((K*N/R+1):((K+1)*N/R));
                dInter=app.interleaving(dT,app.flag);
                dHandled((K*N/R+1):((K+1)*N/R))=dInter(1:N/R);
            end
            dModu=app.cons_map(dHandled,app.flag);
            dPilot=app.add_pilot(dModu);
            dOFDMModu=app.IFFT64(dPilot);
            dCP=add_cp(dOFDMModu);
            %PPDU frame
            PPDU_tmp=[Preamble sCP dCP];
            %Noise
            PPDU=PPDU_tmp+awgn(PPDU_tmp,app.Power,app.Noise);
            
            %--------------------------------------------------------------------------
            %Synchronization
            if ~app.syn(PPDU,Preamble)
                disp('Too much Noise, failed to synchronize');
                app.TextArea.Value{app.index} = sprintf('Too much Noise, failed to synchronize');
                app.index = app.index+1;
                return;
            end
            [~,data_syms,~]=app.FFT64(PPDU);
            
            %Demodulation--------------------------------------------------------------
            %Singal
            sTmp=data_syms(1:48);
            sdeModu=app.decons_map(sTmp,1);
            sdeInter=app.deinterleaving(sdeModu,1);
            sdeConv=app.decoding_conv(sdeInter,1);
            sRec=sdeConv;
            %Data
            dTmp=data_syms(49:tmp/R/Q+48);
            compensation=abs(real(dModu(1)/real(dTmp(1))));
            ddeModu=app.decons_map(dTmp,app.flag,compensation);
            for M=0:length(ddeModu)/(N/R)-1;
                data_t=ddeModu((M*N/R+1):((M+1)*N/R));
                ddeInter=app.deinterleaving(data_t,app.flag);
                dRectmpa((M*N/R+1):((M+1)*N/R))=ddeInter(1:N/R);
            end
            ddeConv=app.decoding_conv(dRectmpa,app.flag);
            dRec=app.scramble(ddeConv);
            
            %Singal output
            
            disp('Data of RX')
            app.TextArea.Value{app.index} = sprintf('Data of RX');
            app.index = app.index+1;
            rx_string = dRec(1:app.Number)
            app.TextArea.Value{app.index} = sprintf('%d',rx_string);
            app.index = app.index+1;
            fprintf('SNR:%f\n',app.snr)
            app.TextArea.Value{app.index} = sprintf('SNR:%f',app.snr);
            app.index = app.index+1;
            %Judge trans?=rec
            if isequal(dTrans,dRec)
                disp('Transmitted=Received Successful!');
                app.TextArea.Value{app.index} = sprintf('Transmitted=Received Successful!');
                app.index = app.index+1;
            else
                disp('Transmitted!=Received Failed!');
                app.TextArea.Value{app.index} = sprintf('Transmitted!=Received Failed!');
                app.index = app.index+1;
            end
            berNum=counting(dTrans(1:app.Number),dRec(1:app.Number));
            ber=(berNum/(app.Number));
            fprintf('Error:%d',berNum);
            app.TextArea.Value{app.index} = sprintf('Error:%d',berNum);
            app.index = app.index+1;
            fprintf('BER:%f',ber);
            app.TextArea.Value{app.index} = sprintf('BER:%f',ber);
            app.index = app.index+1;
            disp('============================');
            app.TextArea.Value{app.index} = sprintf('============================');
            app.index = app.index+1;
        end

        function mod_ofdm_syms = add_pilot(app,mod_syms)

        PilotScramble=[1 1 1 1 -1 -1 -1 1 -1 -1 -1 -1 1 1 -1 1 -1 -1 1 1 -1 1 1 -1 1 1 1 1 ...
              1 1 -1 1 1 1 -1 1 1 -1 -1 1 1 1 -1 1 -1 -1 -1 1 -1 1 -1 -1 1 -1 -1 1 1 1 1 1 -1 -1 1 ...
              1 -1 -1 1 -1 1 -1 1 1 -1 -1 -1 1 1 -1 -1 -1 -1 1 -1 -1 1 -1 1 1 1 1 -1 1 -1 1 -1 1 -1 ...
              -1 -1 -1 -1 1 -1 1 1 -1 1 -1 1 1 1 -1 -1 1 -1 -1 -1 1 1 1 -1 -1 -1 -1 -1 -1 -1];%Pilot scrambled data
        NumDataSubc=48;% amt of data sub-cw
        NumSubc=52;% amt of sub-cw
        DataSubcPatt=[1:5 7:19 21:26 27:32 34:46 48:52]';% pos of data sub-cw
        PilotSubcPatt=[6 20 33 47]';% pos of pilot sub-cw
        NumPilotSubc=4;% amt of pilot sub-cw
        PilotSubcSymbols=[1;1;1;-1];% sign of pilot sub-cw
        
        n_mod_syms = size(mod_syms,2);% Map modu signs transed
        n_ofdm_syms = ceil(n_mod_syms/NumDataSubc);% OFDM signs transed
        mod_syms=[mod_syms zeros(1,n_ofdm_syms*NumDataSubc-n_mod_syms)];
        
        %pilot scrambling pattern
        scramble_patt = repmat(PilotScramble,1,ceil(n_ofdm_syms/length(PilotScramble)));%repeat scrambling pilot，with length equal to OFDM signs' at least
        scramble_patt = scramble_patt(1:n_ofdm_syms);% Cut pilot with same amt of OFDM signs
        
        mod_ofdm_syms = zeros(NumSubc, n_ofdm_syms);
        mod_ofdm_syms(DataSubcPatt,:) = reshape(mod_syms, NumDataSubc, n_ofdm_syms);%Insert mod_syms in data sub-cw, with amt of NumDataSubc 
        
        % Scrambling pilot sub-cw signs seq and insert in the corresponding ones
        mod_ofdm_syms(PilotSubcPatt,:) = repmat(scramble_patt, NumPilotSubc,1).*repmat(PilotSubcSymbols, 1, n_ofdm_syms);
        % output with pilot inserted
        mod_ofdm_syms = mod_ofdm_syms(:).';
        end

        function code=coding_conv(app,seq,flag)
            if flag==1
                t=poly2trellis(7,[133 171]);
                code = convenc(seq,t);
            else
                t=poly2trellis([3 3 3],[7 7 0 4;3 2 7 4;0 2 3 7]);
                code = convenc(seq,t);
            end
        end
        function CM = cons_map(app,dInput,flag)
            Data = dInput;
            if flag==1
                CM=zeros(1,length(Data));
                for i=1:length(Data)
                    if Data(i)<=0
                        CM(i)=-1;
                    else
                        CM(i)=1;
                    end
                end
            elseif flag==2
                DataNum = length(Data);
                Mod = mod(DataNum, 4);
                if Mod==0
                    DataTem=zeros(1,DataNum);
                else
                    DataTem = zeros(1, DataNum+(4-Mod));
                end
                DataTem(1:DataNum) = Data;
                DataCol4 = reshape(DataTem, 4, [])';
                DataHexa = (DataCol4*[8;4;2;1]+1)';
                DataHxaNum = length(DataHexa);
                x = [-3 -1 3 1];
                y = [-3 -1 3 1];
                z = zeros(1,16);
                k = 1;
                for k1 = 1:4
                    for k2 = 1:4
                        z(1, k) = x(1,k1)+y(1,k2)*1i;
                        k = k+1;
                    end
                end
                z = reshape(z, 1, 16);
                for i = 1:DataHxaNum
                    DataTem(1, i) = z(1, DataHexa(1, i));
                end
                scatterplot(DataTem(1:DataHxaNum));
                DataTem = DataTem*(1/sqrt(10));
                CM = DataTem(1:DataHxaNum);
            end
        end
        function Number=count(app,data_one,data_two)
            num=0;
            for k=1:length(data_one)
                if data_one(k)~=data_two(k)
                    num=num+1;
                end
            end
            Number=num;
        end
        function seq=decoding_conv(app,code,flag)
            if flag ==1
                t=poly2trellis(7,[133 171]);
                seq = vitdec(code,t,7,'trunc','hard');
            else
                t=poly2trellis([3 3 3],[7 7 0 4;3 2 7 4;0 2 3 7]);
                seq = vitdec(code,t,3,'trunc','hard');
            end
        end
        function DCM = decons_map(app,input_data,flag,compensation)
            Data = input_data;
            if flag==1
                DCM=zeros(1,length(Data));
                for i=1:length(Data)
                    if Data(i)<0
                        DCM(i)=0;
                    else
                        DCM(i)=1;
                    end
                end
            elseif flag==2
                DataNum = length(Data);
                Data = Data*(sqrt(10));
                NewData = zeros(1, DataNum);
                x = [-3 -1 3 1];
                y = [-3 -1 3 1];
                z = zeros(1,16);
                k = 1;
                for k1 = 1:4
                    for k2 = 1:4
                        z(1, k) = x(1,k1)+y(1,k2)*1i;
                        k = k+1;
                    end
                end
                z = reshape(z, 1, 16);
                Data=Data*compensation;
                for k=1:DataNum
                    for k1 = 1:16
                        if abs(Data(1, k)-z(1, k1))<(0.1)
                            NewData(1, k) = k1;
                        end
                    end
                end
                NewData = NewData-1;
                Num = DataNum*4;
                FData = zeros(1, Num);
                k2 = 0;
                for k = 1:DataNum
                    k3 = 4;
                    for k1 = 1:4
                        FData(1,k2+k3) = mod(NewData(1, k), 2);
                        NewData(1, k) = floor(NewData(1, k)/2);
                        k3 = k3-1;
                    end
                    k2 = k2+4;
                end
                scatterplot(Data);
                DCM = FData;
            end
        end
        function DEINTER = deinterleaving(app,dInput,flag )
            Data = dInput;
            DataNum = length(Data);
            if flag==1
                Ncbps = 48;
                Nbpsc =1;
            else
                Ncbps = 192;
                Nbpsc =4;
            end
            Mod = mod(DataNum, Ncbps);
            NewD = zeros(1, DataNum);
            Nend = zeros(1, DataNum);
            s = max(Nbpsc/2, 1);
            for j = 0:DataNum-1
                t1 = Data(1, j+1);
                i = s*floor(j/s)+mod((j+floor(16*j/Ncbps)),s);
                NewD(1, i+1) = t1;
            end
            for i =0:length(NewD)-1
                t1 = NewD(1,i+1);
                k = 16*i-(Ncbps-1)*floor(16*i/Ncbps);
                Nend(1, k+1) = t1;
            end
            if flag==1
                DEINTER = Nend;
            else
                DEINTER = Nend;
            end
        end
        function rx_time_data=delete_cp(app,rx_frame)
            
            frame_length=length(rx_frame);
            rx_data=rx_frame(321:frame_length);
            num_symbols =floor(size(rx_data, 2)/80);%Need Sign received（80 elements/sign）
            rx_data=rx_data(1:80*num_symbols);
            
            % Delete CP
            symbols = reshape(rx_data(:), 80, num_symbols);
            symbols(1:16,:)=[];
            rx_time_data = symbols;
        end
        function data_in=descramble(app,data_out)
            D_init=[1,1,1,1,1,1,1];
            for k=1:127
                EX=xor(D_init(4),D_init(7));
                D_init=circshift(D_init,[1,1]);
                D_init(1)=EX;
                data(k)=EX;
            end
            len=length(data_out);
            n=mod(len,127);
            if n==0
                data=repmat(data,1,floor(len/127));
                data_in=xor(data_out,data);
            else
                for m=1:n
                    d(m)=data(m);
                end
                data=repmat(data,1,floor(len/127));
                r=[data,d];
                data_in=xor(data_out,r);
            end
        end
        function [freq_tr_syms, freq_data_syms, freq_pilot_syms] = FFT64(app,time_signal)
            
            UsedSubcIdx=[7:32 34:59]';
            DataSubcIdx=[7:11 13:25 27:32 34:39 41:53 55:59]';
            PilotSubcIdx=[12 26 40 54]';
            
            long_tr_syms = time_signal(193:320);%提取时域长训练符号
            
            long_tr_syms = reshape(long_tr_syms, 64, 2);
            % 转换到频域
            freq_long_tr = fft(long_tr_syms)/(64/sqrt(52));%转换到频域
            reorder = [33:64 1:32];%重排序
            freq_long_tr(reorder,:) = freq_long_tr;
            % 选择训练符号
            freq_tr_syms= freq_long_tr(UsedSubcIdx,:);
            freq_tr_syms= freq_tr_syms(:).';
            
            % 提取Data 字段
            freq_data = delete_cp(time_signal);
            % 执行FFT
            freq_data = fft(freq_data)/(64/sqrt(52));
            %%重排序 [33:64 1:32]
            freq_data(reorder,:) = freq_data;
            %选择数据子载波
            freq_data_syms = freq_data(DataSubcIdx,:);
            %选择导频子载波
            freq_pilot_syms = freq_data(PilotSubcIdx,:);
            
            freq_data_syms = freq_data_syms(:).';
            freq_pilot_syms = freq_pilot_syms(:).';
        end
        function  time_syms= IFFT64(app,mod_ofdm_syms);
            %IFFT64：信号由频域变换到时域
            UsedSubcIdx=[7:32 34:59]';%使用的子载波索引
            NumSubc=52;%子载波数
            
            %OFDM符号数
            num_symbols =size(mod_ofdm_syms, 2)/NumSubc;
            
            %将数据加到相应子载波上，并在未使用的子载波上补0
            syms_into_ifft = zeros(64, num_symbols);
            syms_into_ifft(UsedSubcIdx,:) = reshape(mod_ofdm_syms(:),NumSubc, num_symbols);
            
            %数据位置置换
            resample_patt=[33:64 1:32];
            syms_into_ifft(resample_patt,:) = syms_into_ifft;
            
            % 变换到时域
            time_syms = zeros(1,num_symbols*64);%初始化time_syms
            ifft_out = ifft(syms_into_ifft);
            time_syms(1,:) = ifft_out(:).';
        end
        function INTER = interleaving(app,dInput,flag )
            DataNum = length(dInput);
            if flag==1
                Ncbps = 48;
                Nbpsc =1;
            else
                Ncbps = 192;
                Nbpsc =4;
            end
            Mod = mod(DataNum, Ncbps);
            t = Ncbps-Mod;
            if t==Ncbps
                t = 0;
            end
            %Add Zero
            Data = dInput;
            NewD = zeros(1, DataNum+t);
            Nend = zeros(1, DataNum+t);
            InputD = zeros(1, DataNum+t);
            %Initial self-sequence，1st metathesis seq,2nd metathesis seq
            InputD(1:DataNum) = Data(1:DataNum);
            Data = InputD;
            DevideNum = (DataNum+t)/Nbpsc;
            Devide = reshape(Data, Nbpsc, DevideNum);
            Devide = reshape(Devide, 1, []);
            %Serial transform
            Data = Devide;
            s = max(Nbpsc/2, 1);
            t = length(Data);
            for k = 0:length(Data)-1
                t1 = Data(1, k+1);
                i = (Ncbps/16)*(mod(k,16))+floor(k/16);
                NewD(1, i+1) = t1;
                %1st metathesis
            end
            
            for i =0:length(NewD)-1
                t1 = NewD(1,i+1);
                j = s*floor(i/s)+mod((i+Ncbps-floor(16*i/Ncbps)), s);
                Nend(1, j+1) = t1;
                %2nd metathesis
            end
            INTER = Nend;
        end
        function preamble=preamble(app)
            S = [0, 0, 1+i, 0, 0, 0, -1-i, 0, 0, 0, 1+i, 0, 0, 0, -1-i, 0, 0, 0, -1-i, 0, 0, 0, 1+i, 0, 0, 0, 0, 0, 0, -1-i, 0, 0, 0, -1-i, 0, 0, 0, 1+i, 0, 0, 0, 1+i, 0, 0, 0, 1+i 0, 0, 0, 1+i, 0, 0];
            L = [1, 1, -1, -1, 1, 1, -1, 1, -1, 1, 1, 1, 1, 1, 1,  -1, -1, 1, 1, -1, 1, -1, 1, 1, 1, 1, 1, -1, -1, 1, 1, -1, 1, -1, 1, -1, -1, -1, -1, -1, 1, 1, -1, -1, 1, -1, 1, -1, 1, 1, 1, 1];
            Sk = (13/6)^(1/2)* S;
            %Short---------------------------------------------------------------------
            short = app.IFFT64(Sk);
            short=short(1:16);
            short_str=repmat(short,1,10);
            short_str(1) =  short_str(1)*0.5;
            %Long----------------------------------------------------------------------
            long = app.IFFT64(L);
            GI2 = long(33:64);
            long_str = [GI2,long,long];
            long_str(1) = long_str(1)*0.5+short_str(1);
            
            preamble =[short_str,long_str];
        end
        function data_out=scramble(app,dInput)
            D_init=[1,1,1,1,1,1,1];
            for k=1:127
                EX=xor(D_init(4),D_init(7));
                D_init=circshift(D_init,[1,1]);
                D_init(1)=EX;
                data(k)=EX;
            end
            len=length(dInput);
            n=mod(len,127);
            if n==0
                data=repmat(data,1,floor(len/127));
                data_out=xor(dInput,data);
            else
                for m=1:n
                    d(m)=data(m);
                end
                data=repmat(data,1,floor(len/127));
                r=[data,d];
                data_out=double(xor(dInput,r));
            end
        end
        function P=syn(app,PPDU,preamble)
            P=1;
            b=0;
            d=preamble;
            a=[];
            for i=1:200
                a=abs(dot(PPDU(i:i+15),d(1:16)));%dot:Conjugating and transposition of [data(i:i+15)] *d(1:16)
                if a>1.5%Peak value
                    b=b+1;
                    if b==10
                        v=abs(i-144);
                        a=PPDU(v:length(PPDU));%One period exists, store the remnent data to another.
                    elseif b~=10&&i==200
                        P=0;
                        break;
                    end
                end
            end
        end
        function time_signal = add_cp(app,time_syms)

        num_symbols = size(time_syms, 2)/64;%Trans Sign neeeded （64 elements/sign）
        time_signal = zeros(1, num_symbols*80);%Produce original state in time domin
        
        % Add CP
        symbols = reshape(time_syms(:), 64, num_symbols);
        tmp_syms = [symbols(49:64,:); symbols]; 
        tmp_syms(1,:)=tmp_syms(1,:)*0.5;%Add windows in both head and tail of sign
        tmp_syms_end(1,:)=symbols(1,:)*0.5;
        
        tmp_syms(1,2:num_symbols)=tmp_syms(1,2:num_symbols)+tmp_syms_end(1:num_symbols-1);
        time_signal(:) = tmp_syms(:).';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: NumofTransEditField
        function NumofTransEditFieldValueChanged(app, event)
            value = app.NumofTransEditField.Value;
            app.Number = value;
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
      
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.main()
        end

        % Value changed function: PowerofNoiseEditField
        function PowerofNoiseEditFieldValueChanged(app, event)
            value = app.PowerofNoiseEditField.Value;
            app.Noise = value;
        end

        % Selection changed function: ModulationButtonGroup
        function ModulationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModulationButtonGroup.SelectedObject;
           if selectedButton == app.QAM36MbpsButton
               app.flag = 2;
           else
               app.flag =1;
           end
         
        end

        % Value changed function: PowerofTransEditField
        function PowerofTransEditFieldValueChanged(app, event)
            value = app.PowerofTransEditField.Value;
            app.Power = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModulationButtonGroup = uibuttongroup(app.LeftPanel);
            app.ModulationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModulationButtonGroupSelectionChanged, true);
            app.ModulationButtonGroup.Title = 'Modulation';
            app.BPSK6MpbsButton = uiradiobutton(app.ModulationButtonGroup);
            app.BPSK6MpbsButton.Text = 'BPSK(6Mpbs)';
            app.BPSK6MpbsButton.Value = true;
            app.QAM36MbpsButton = uiradiobutton(app.ModulationButtonGroup);
            app.QAM36MbpsButton.Text = '16QAM(36Mbps)';
            app.NumofTransEditFieldLabel = uilabel(app.LeftPanel);
            app.NumofTransEditFieldLabel.Text = 'Num of Trans';
            app.NumofTransEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumofTransEditField.ValueChangedFcn = createCallbackFcn(app, @NumofTransEditFieldValueChanged, true);
            app.PowerofNoiseEditFieldLabel = uilabel(app.LeftPanel);
            app.PowerofNoiseEditFieldLabel.Text = 'Power of Noise';
            app.PowerofNoiseEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PowerofNoiseEditField.ValueChangedFcn = createCallbackFcn(app, @PowerofNoiseEditFieldValueChanged, true);
            app.IEEE80211aOFDMPHYLabel = uilabel(app.LeftPanel);
            app.IEEE80211aOFDMPHYLabel.Text = 'IEEE 802.11a OFDM PHY ';
            app.SwitchLabel = uilabel(app.LeftPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.PowerofTransEditFieldLabel = uilabel(app.LeftPanel);
            app.PowerofTransEditFieldLabel.Text = 'Power of Trans';
            app.PowerofTransEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PowerofTransEditField.ValueChangedFcn = createCallbackFcn(app, @PowerofTransEditFieldValueChanged, true);
            app.SimulationLabel = uilabel(app.LeftPanel);
            app.SimulationLabel.Text = 'Simulation ';
            app.Version10Label = uilabel(app.LeftPanel);
            app.Version10Label.Text = 'Version 1.0';
            app.RightPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.RightPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = '80211_logo.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end